classdef trab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EditarTab                       matlab.ui.container.Tab
        AlgoritmoListBoxLabel           matlab.ui.control.Label
        AlgoritmoListBox                matlab.ui.control.ListBox
        MiniImagem                      matlab.ui.control.UIAxes
        AplicarButton                   matlab.ui.control.Button
        OpesPanel                       matlab.ui.container.Panel
        VoltarButton                    matlab.ui.control.Button
        LimparTudoButton                matlab.ui.control.Button
        CarregarImagemTab               matlab.ui.container.Tab
        ImagemSelecionada               matlab.ui.control.UIAxes
        EncontrarButton                 matlab.ui.control.Button
        SelecioneoarquivodaimagemLabel  matlab.ui.control.Label
        ImagemAtual                     matlab.ui.control.UIAxes
    end

    %% LEMBRAR DE TENTAR FAZER O VOLTARRR FUNCIONARRR SOCORRO 
    properties (Access = private)
        imagemCarregada % Description
        
        imagemProcessada % Description
        
        figureThreshold
        
        listaImagens
        
        figurePassaBaixa % Description
        
        figurePassaAlta % Description
        
        figureRoberts
        
        figurePrewitt
        
        figureSobel
        
        numeroImagem
        
        numeroAuxiliarImagem
    end

    methods (Access = private)
    
        
        function dialogThreshold(app)
            
               function aplicarThreshold(~, ~, app, Value)
                    app.imagemProcessada = im2bw(app.imagemProcessada, Value);
                    imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                    salvarImagem(app, "Threshold");
                    close(app.figureThreshold);
               end
               
               app.figureThreshold = uifigure;
               app.figureThreshold.Position = [300 300 250 100];    
              
               spn = uispinner(app.figureThreshold);
               spn.Limits = [0 1];
               spn.Value = 0.5;
               spn.Step = 0.1;
               spn.Position = [150 65 60 20];
               
               txt = uilabel(app.figureThreshold);
               txt.Text = 'Valor do Threshold';
               txt.Position = [40 65 120 20];
               
               btn = uibutton(app.figureThreshold, 'ButtonPushedFcn', @(btn,event) aplicarThreshold(btn, event, app, spn.Value));
               btn.Text = 'Aplicar';
               btn.Position = [90 25 80 20];
               
        end
    
         function dialogPassaBaixa(app)
            
               function aplicarPassaBaixa(~, ~, app, Value, Value2)
                   if strcmp(Value2, 'Média') == 1
                       Matriz = ones(Value);
                       Divisor = (Value * Value)*ones(Value);
                       x = Matriz./Divisor; 
                       app.imagemProcessada = imfilter(app.imagemProcessada, x);
                       imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                       salvarImagem(app, "PassaBaixaMedia");
                       close(app.figurePassaBaixa);
                   else
                       %Faz pela mediana
                       dimensao = [Value, Value];
                       
                       app.imagemProcessada = medfilt2(app.imagemProcessada, dimensao);
                       imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                       salvarImagem(app, "PassaBaixaMediana");
                       close(app.figurePassaBaixa);

                   end
               end
               
               app.figurePassaBaixa = uifigure;
               app.figurePassaBaixa.Position = [300 300 250 120];    
               
               dropdown = uidropdown(app.figurePassaBaixa);
               dropdown.Items = {'Média', 'Mediana'};
               dropdown.Position = [110 85 100 20];
               
               txt = uilabel(app.figurePassaBaixa);
               txt.Text = 'Tipo';
               txt.Position = [80 85 120 20];
               
               spn = uispinner(app.figurePassaBaixa);
               spn.Limits = [1 100];
               spn.Value = 3;
               spn.Step = 1;
               spn.Position = [150 50 60 20];
               
               txt = uilabel(app.figurePassaBaixa);
               txt.Text = 'Tamanho da máscara';
               txt.Position = [20 50 120 20];
               
               btn = uibutton(app.figurePassaBaixa, 'ButtonPushedFcn', @(btn,event) aplicarPassaBaixa(btn, event, app, spn.Value, dropdown.Value));
               btn.Text = 'Aplicar';
               btn.Position = [90 15 80 20];
               
        end

        function dialogPassaAlta(app)
            
               function aplicarPassaAlta(~, ~, app, Value, Value2)
                   if strcmp(Value2, 'Básico') == 1
                       Matriz = -ones(Value);
                       
                       Divisor = (Value * Value)*ones(Value);
                       x = Matriz./Divisor; 
                       app.imagemProcessada = imfilter(app.imagemProcessada, x);
                       imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                       salvarImagem(app, PassaAltaBasico);
                       close(app.figurePassaBaixa);
                   else
                       %Faz pela mediana
                   end
               end
               
               app.figurePassaAlta = uifigure;
               app.figurePassaAlta.Position = [300 300 250 120];    
               
               dropdown = uidropdown(app.figurePassaAlta);
               dropdown.Items = {'Básico', 'Alto Reforço'};
               dropdown.Position = [110 85 100 20];
               
               txt = uilabel(app.figurePassaAlta);
               txt.Text = 'Tipo';
               txt.Position = [80 85 120 20];
               
               spn = uispinner(app.figurePassaAlta);
               spn.Limits = [1 100];
               spn.Value = 3;
               spn.Step = 1;
               spn.Position = [150 50 60 20];
               
               txt = uilabel(app.figurePassaAlta);
               txt.Text = 'Tamanho da máscara';
               txt.Position = [20 50 120 20];
               
               btn = uibutton(app.figurePassaAlta, 'ButtonPushedFcn', @(btn,event) aplicarPassaAlta(btn, event, app, spn.Value, dropdown.Value));
               btn.Text = 'Aplicar';
               btn.Position = [90 15 80 20];
               
        end
    
        function dialogRoberts(app)
            
               function aplicarRoberts(~, ~, app, Value, Value2)
                   if strcmp(Value2, '45°') == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'Roberts', Value, 'vertical');
                       imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                       salvarImagem(app, "Roberts45Graus");
                       close(app.figureRoberts);
                   elseif strcmp(Value2, '135°') == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'Roberts', Value, 'horizontal');
                       imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                       salvarImagem(app, "Roberts135Graus");
                       close(app.figureRoberts);
                   else
                       app.imagemProcessada = edge(app.imagemProcessada, 'Roberts', Value, 'both');
                       imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                       salvarImagem(app, "RobertsAmbosOsGraus");
                       close(app.figureRoberts);

                   end
               end
               
               app.figureRoberts = uifigure;
               app.figureRoberts.Position = [300 300 250 120];    
               
               dropdown = uidropdown(app.figureRoberts);
               dropdown.Items = {'45°', '135°', 'Ambos'};
               dropdown.Position = [150 85 60 20];
               
               txt = uilabel(app.figureRoberts);
               txt.Text = 'Ângulos das bordas';
               txt.Position = [28 90 120 20];
               
               txt2 = uilabel(app.figureRoberts);
               txt2.Text = 'com a horizontal';
               txt2.Position = [48 75 120 20];
               
               spn = uispinner(app.figureRoberts);
               spn.Limits = [0 1];
               spn.Value = 0;
               spn.Step = 0.01;
               spn.Position = [150 50 60 20];
               
               txt = uilabel(app.figureRoberts);
               txt.Text = 'Threshold';
               txt.Position = [80 50 120 20];
               
               btn = uibutton(app.figureRoberts, 'ButtonPushedFcn', @(btn,event) aplicarRoberts(btn, event, app, spn.Value, dropdown.Value));
               btn.Text = 'Aplicar';
               btn.Position = [90 15 80 20];
               
        end
    
        function dialogPrewitt(app)
            
               function aplicarPrewitt(~, ~, app, Value, Value2)
                   if strcmp(Value2, 'Vertical') == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'Prewitt', Value, 'vertical');
                       imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                       salvarImagem(app, "PrewittVertical");
                       close(app.figurePrewitt);
                   elseif strcmp(Value2, 'Horizontal') == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'Prewitt', Value, 'horizontal');
                       imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                       salvarImagem(app, "PrewittHorizontal");
                       close(app.figurePrewitt);
                   else
                       app.imagemProcessada = edge(app.imagemProcessada, 'Prewitt', Value, 'both');
                       imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                       salvarImagem(app, "PrewittAmbos");
                       close(app.figurePrewitt);

                   end
               end
               
               app.figurePrewitt = uifigure;
               app.figurePrewitt.Position = [300 300 250 120];    
               
               dropdown = uidropdown(app.figurePrewitt);
               dropdown.Items = {'Vertical', 'Horizontal', 'Ambos'};
               dropdown.Position = [130 85 80 20];
               
               txt = uilabel(app.figurePrewitt);
               txt.Text = 'Direção das bordas';
               txt.Position = [10 85 120 20];               
               spn = uispinner(app.figurePrewitt);
               spn.Limits = [0 1];
               spn.Value = 0;
               spn.Step = 0.01;
               spn.Position = [150 50 60 20];
               
               txt = uilabel(app.figurePrewitt);
               txt.Text = 'Threshold';
               txt.Position = [60 50 120 20];
               
               btn = uibutton(app.figurePrewitt, 'ButtonPushedFcn', @(btn,event) aplicarPrewitt(btn, event, app, spn.Value, dropdown.Value));
               btn.Text = 'Aplicar';
               btn.Position = [90 15 80 20];
               
        end
    
        function dialogSobel(app)
            
               function aplicarSobel(~, ~, app, Value, Value2)
                   if strcmp(Value2, 'Vertical') == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'Sobel', Value, 'vertical');
                       imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                       salvarImagem(app, "SobelVertical");
                       close(app.figureSobel);
                   elseif strcmp(Value2, 'Horizontal') == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'Sobel', Value, 'horizontal');
                       imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                       salvarImagem(app, "SobelHorizontal");
                       close(app.figureSobel);
                   else
                       app.imagemProcessada = edge(app.imagemProcessada, 'Sobel', Value, 'both');
                       imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                       salvarImagem(app, "SobelAmbos");
                       close(app.figureSobel);

                   end
               end
               
               app.figureSobel = uifigure;
               app.figureSobel.Position = [300 300 250 120];    
               
               dropdown = uidropdown(app.figureSobel);
               dropdown.Items = {'Vertical', 'Horizontal', 'Ambos'};
               dropdown.Position = [130 85 80 20];
               
               txt = uilabel(app.figureSobel);
               txt.Text = 'Direção das bordas';
               txt.Position = [10 85 120 20];               
               spn = uispinner(app.figureSobel);
               spn.Limits = [0 1];
               spn.Value = 0;
               spn.Step = 0.01;
               spn.Position = [150 50 60 20];
               
               txt = uilabel(app.figureSobel);
               txt.Text = 'Threshold';
               txt.Position = [60 50 120 20];
               
               btn = uibutton(app.figureSobel, 'ButtonPushedFcn', @(btn,event) aplicarSobel(btn, event, app, spn.Value, dropdown.Value));
               btn.Text = 'Aplicar';
               btn.Position = [90 15 80 20];
               
        end

        function getImagemAtual(app)
                a = num2str(app.numeroAuxiliarImagem);
                string = "imagemSalva" + a + ".png";
                app.imagemProcessada = imread(convertStringsToChars(string));
        end
        
        function salvarImagem(app, nome)
            a = num2str(app.numeroImagem);
            string = nome + a + ".png";
            imwrite(app.imagemProcessada, convertStringsToChars(string));
            app.numeroAuxiliarImagem = app.numeroImagem;
            app.numeroImagem = app.numeroImagem + 1;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.ImagemSelecionada, 'XTick', []);
            set(app.ImagemSelecionada, 'YTick', []);
            set(app.ImagemAtual, 'XTick', []);
            set(app.ImagemAtual, 'YTick', []);
            set(app.MiniImagem, 'XTick', []);
            set(app.MiniImagem, 'YTick', []);
            app.numeroImagem = 1;
            app.numeroAuxiliarImagem = 1;
        end

        % Button pushed function: EncontrarButton
        function EncontrarButtonPushed(app, event)
            [filename,user_canceled] = imgetfile();
            if user_canceled == false
                app.imagemCarregada = imread(filename);
                app.listaImagens = {app.imagemCarregada};
                app.imagemProcessada = app.imagemCarregada;
                imshow(app.imagemCarregada, 'Parent', app.ImagemSelecionada);  
                imshow(app.imagemCarregada, 'Parent', app.MiniImagem);
            end     
            % [filename,pathname] = uigetfile({'.jpeg', '.'},'Select file');
            
            
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            value = app.AlgoritmoListBox.Value;
            if strcmp(value, 'Escala de Cinza') == 1
                app.imagemProcessada = rgb2gray(app.imagemProcessada);
                imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                app.listaImagens = {app.listaImagens; app.imagemProcessada};
                salvarImagem(app, "Grayscale");
            elseif strcmp(value, 'Limiarização') == 1
                dialogThreshold(app);
            elseif strcmp(value, 'Passa-Baixa') == 1
                dialogPassaBaixa(app);
            elseif strcmp(value, 'Passa-Alta') == 1
                dialogPassaAlta(app);
            elseif strcmp(value, 'Roberts') == 1
                dialogRoberts(app);
            elseif strcmp(value, 'Prewitt') == 1
                dialogPrewitt(app);
            elseif strcmp(value, 'Sobel') == 1
                dialogSobel(app);
            elseif strcmp(value, 'Log') == 1
            elseif strcmp(value, 'Zerocross') == 1
            elseif strcmp(value, 'Canny') == 1
            elseif strcmp(value, 'Ruídos') == 1
            elseif strcmp(value, 'Watershed') == 1
            elseif strcmp(value, 'Contagem de Objetos') == 1
            end
        end

        % Button pushed function: LimparTudoButton
        function LimparTudoButtonPushed(app, event)
            app.imagemProcessada = app.imagemCarregada;
            imshow(app.imagemCarregada, 'Parent', app.ImagemAtual);
        end

        % Button pushed function: VoltarButton
        function VoltarButtonPushed(app, event)
            if app.numeroAuxiliarImagem >= 1
                getImagemAtual(app);
                imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                app.numeroAuxiliarImagem = app.numeroAuxiliarImagem - 1;
            else
                app.imagemProcessada = app.imagemCarregada;
                imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EditarTab = uitab(app.TabGroup);
            app.EditarTab.Title = 'Editar';
            app.AlgoritmoListBoxLabel = uilabel(app.EditarTab);
            app.AlgoritmoListBoxLabel.Text = 'Algoritmo';
            app.AlgoritmoListBox = uilistbox(app.EditarTab);
            app.AlgoritmoListBox.Items = {'Limiarização', 'Escala de Cinza', 'Passa-Alta', 'Passa-Baixa', 'Roberts', 'Prewitt', 'Sobel', 'Log', 'Zerocross', 'Canny', 'Ruídos', 'Watershed', 'Contagem de Objetos'};
            app.AlgoritmoListBox.Value = 'Limiarização';
            app.MiniImagem = uiaxes(app.EditarTab);
            app.AplicarButton = uibutton(app.EditarTab, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.OpesPanel = uipanel(app.EditarTab);
            app.OpesPanel.Title = 'Opções';
            app.VoltarButton = uibutton(app.OpesPanel, 'push');
            app.VoltarButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarButtonPushed, true);
            app.VoltarButton.Text = 'Voltar';
            app.LimparTudoButton = uibutton(app.OpesPanel, 'push');
            app.LimparTudoButton.ButtonPushedFcn = createCallbackFcn(app, @LimparTudoButtonPushed, true);
            app.LimparTudoButton.Text = 'Limpar Tudo';
            app.CarregarImagemTab = uitab(app.TabGroup);
            app.CarregarImagemTab.Title = 'Carregar Imagem';
            app.ImagemSelecionada = uiaxes(app.CarregarImagemTab);
            app.EncontrarButton = uibutton(app.CarregarImagemTab, 'push');
            app.EncontrarButton.ButtonPushedFcn = createCallbackFcn(app, @EncontrarButtonPushed, true);
            app.EncontrarButton.Text = 'Encontrar';
            app.SelecioneoarquivodaimagemLabel = uilabel(app.CarregarImagemTab);
            app.SelecioneoarquivodaimagemLabel.Text = 'Selecione o arquivo da imagem';
            app.ImagemAtual = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = trab1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end